classdef brightness < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        BackButton             matlab.ui.control.Button
        Image                  matlab.ui.control.Image
        SavechangesButton      matlab.ui.control.Button
        BrightnessSlider       matlab.ui.control.Slider
        BrightnessSliderLabel  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    properties (Access = private)
        ImagePath;
    end
    methods (Access = public)
        function setImagePath(app, imagePath)
            app.ImagePath = imagePath;
            if ~isempty(app.ImagePath)
                app.Image.ImageSource = app.ImagePath;
                imageData = imread(app.ImagePath);
                imshow(imageData, 'Parent', app.UIAxes);
                app.Image.UserData = imageData;
                
                % Set slider limits
                app.BrightnessSlider.Limits = [0 200];
                app.BrightnessSlider.Value = 100;  % Set default value to middle (original brightness)
                
                title(app.UIAxes, 'Original Image');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SavechangesButton
        function SavechangesButtonPushed(app, event)
            axesImage = getimage(app.UIAxes);
    
            if isempty(axesImage)
                errordlg('No image to save. Please adjust the brightness first.', 'Save Error');
                return;
            end
            
            % Get the original file name and path
            [filepath, name, ext] = fileparts(app.ImagePath);
            
            % Create new file name
            newFileName = fullfile(filepath, [name '_brightness' ext]);
            
            % Ensure the new file name is unique
            counter = 1;
            while isfile(newFileName)
                newFileName = fullfile(filepath, [name '_brightness_' num2str(counter) ext]);
                counter = counter + 1;
            end
            
            % Save the modified image
            imwrite(axesImage, newFileName);
            
            % Create a new instance of first_interface
            firstInterface = first_interface;
            
            % Set the image path in the new first_interface to the new file
            firstInterface.setImagePath(newFileName);
            
            % Make the new first_interface visible
            firstInterface.UIFigure.Visible = 'on';
            
            % Close the current brightness app
            delete(app);
        end

        % Value changing function: BrightnessSlider
        function BrightnessSliderValueChanging(app, event)
            changingValue = event.Value;

            % Check if the original image data is already stored
            if isempty(app.Image.UserData)
                % Load the original image and store it in the Image's UserData
                app.Image.UserData = imread(app.Image.ImageSource);
                
                % Set slider limits
                app.BrightnessSlider.Limits = [0 200];
                app.BrightnessSlider.Value = 100;  % Set default value to middle (original brightness)
                
                % Display the original image in UIAxes
                imshow(app.Image.UserData, 'Parent', app.UIAxes);
                title(app.UIAxes, 'Original Image');
                return;  % Exit the function after displaying the original image
            end
            
            % Get the original image data
            originalImageData = app.Image.UserData;
            
            % Convert the image to double precision for calculations
            adjustedImage = im2double(originalImageData);
            
            % Apply white balance adjustment
            % Map the slider range [0, 200] to [0.5, 2] for brightness adjustment
            brightnessScale = changingValue / 100;
            adjustedImage = adjustedImage * brightnessScale;
            
            % Clip values to ensure they're in the valid range [0, 1]
            adjustedImage = min(max(adjustedImage, 0), 1);
            
            % Display the adjusted image in UIAxes
            imshow(adjustedImage, 'Parent', app.UIAxes);
            
            % Update axis labels
            title(app.UIAxes, sprintf('Adjusted Image (Brightness: %.2f)', brightnessScale));
            xlabel(app.UIAxes, '');
            ylabel(app.UIAxes, '');
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            firstInterface = first_interface;
            firstInterface.setImagePath(app.ImagePath);
            firstInterface.UIFigure.Visible = 'on';
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.BrightnessSliderLabel = uilabel(app.UIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.UIFigure);
            app.BrightnessSlider.ValueChangingFcn = createCallbackFcn(app, @BrightnessSliderValueChanging, true);
            app.SavechangesButton = uibutton(app.UIFigure, 'push');
            app.SavechangesButton.ButtonPushedFcn = createCallbackFcn(app, @SavechangesButtonPushed, true);
            app.SavechangesButton.Text = 'Save changes';
            app.Image = uiimage(app.UIFigure);
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = brightness

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end