classdef contrast < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        BackButton           matlab.ui.control.Button
        ContrastSlider       matlab.ui.control.Slider
        ContrastSliderLabel  matlab.ui.control.Label
        SaveChangesButton    matlab.ui.control.Button
        Image                matlab.ui.control.Image
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImagePath
    end

    methods (Access = public)
        function setImagePath(app, imagePath)
            app.ImagePath = imagePath;
            if ~isempty(app.ImagePath)
                app.Image.ImageSource = app.ImagePath;
                imageData = imread(app.ImagePath);
                imshow(imageData, 'Parent', app.UIAxes);
                app.UIAxes.UserData.ImageSource = imageData;
                title(app.UIAxes, 'Original Image');
                
                % Initialize with default contrast
                app.contrastImage(app.ContrastSlider.Value);
            end
        end

        function contrastImage(app, contrastMultiplier)
            if ~isempty(app.ImagePath)
                imageData = app.UIAxes.UserData.ImageSource;
                
                % Turn to hsv
                hsvImage = rgb2hsv(imageData);
        
                v = hsvImage(:, :, 3);
        
                % Value Formula
                V = 0.5 + contrastMultiplier * (v - 0.5);
                
                % Cannot Exceed 1, or below 0
                V = min(max(V, 0), 1);
        
                % Apply new Value
                hsvImage(:,:,3) = V;
        
                % Convert HSV back to RGB
                contrastedImage = hsv2rgb(hsvImage);
        
                % Display contrasted image
                imshow(contrastedImage, 'Parent', app.UIAxes);
                app.UIAxes.UserData.ContrastedImage = contrastedImage;
                title(app.UIAxes, 'Contrasted Image');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            contrastMultiplier = event.Value;
            app.contrastImage(contrastMultiplier)
        end

        % Button pushed function: SaveChangesButton
        function SaveChangesButtonPushed(app, event)
            if isfield(app.UIAxes.UserData, 'ImageSource')
                if isfield(app.UIAxes.UserData, 'ContrastedImage')
                    imageToSave = app.UIAxes.UserData.ContrastedImage;
                else
                    imageToSave = app.UIAxes.UserData.ImageSource;
                end
                
                % Get the original file name and path
                [filepath, name, ext] = fileparts(app.ImagePath);
                
                % Create new file name
                newFileName = fullfile(filepath, [name '_contrasted' ext]);
                
                % Ensure the new file name is unique
                counter = 1;
                while isfile(newFileName)
                    newFileName = fullfile(filepath, [name '_contrasted_' num2str(counter) ext]);
                    counter = counter + 1;
                end
                
                % Save the modified image
                imwrite(imageToSave, newFileName);
                
                % Create a new instance of first_interface
                firstInterface = first_interface;
                
                % Set the image path in the new first_interface to the new file
                firstInterface.setImagePath(newFileName);
                
                % Make the new first_interface visible
                firstInterface.UIFigure.Visible = 'on';
                
                % Close the current contrast app
                delete(app);
            else
                uialert(app.UIFigure, 'No image to save.', 'Save Error');
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            firstInterface = first_interface;
            firstInterface.setImagePath(app.ImagePath);
            firstInterface.UIFigure.Visible = 'on';
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Contrast')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Image = uiimage(app.UIFigure);
            app.SaveChangesButton = uibutton(app.UIFigure, 'push');
            app.SaveChangesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChangesButtonPushed, true);
            app.SaveChangesButton.Text = 'Save Changes';
            app.ContrastSliderLabel = uilabel(app.UIFigure);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.UIFigure);
            app.ContrastSlider.Limits = [0 2];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.Value = 1;
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = contrast

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end