classdef first_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ExportButton      matlab.ui.control.Button
        ExitButton        matlab.ui.control.Button
        AddimageButton    matlab.ui.control.Button
        PresetsButton     matlab.ui.control.Button
        ContrastButton    matlab.ui.control.Button
        BlurButton        matlab.ui.control.Button
        BrightnessButton  matlab.ui.control.Button
        SaturationButton  matlab.ui.control.Button
        ColorButton       matlab.ui.control.Button
        Image             matlab.ui.control.Image
        CropButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        SelectedImagePath % Path to the selected image
    end

    methods (Access = public)
        function setImagePath(app, imagePath)
            app.SelectedImagePath = imagePath;
            if ~isempty(app.SelectedImagePath)
                app.Image.ImageSource = app.SelectedImagePath;
            end
        end
    end

    methods (Access = private)

        function updateImageBlur(app,ax, orig, blurSlider)
                % Get the blur scale value from the slider
                blurScale = blurSlider.Value;
                        
                % Apply the blur effect to the original image
                editedImage = imgaussfilt(orig, blurScale);
                        
                % Display the edited image in the specified axes
                imshow(editedImage, 'Parent', ax);
                        
                % Store the edited image in the axes UserData for potential saving
                ax.UserData.EditedImage = editedImage;
            end

        function editedImage = colorChange(app, img, redScale, greenScale, blueScale)
            % Convert image to double for 0-1 rgb range
            img = im2double(img);
            
            % Extract rgb channels
            redChannel = img(:, :, 1);
            greenChannel = img(:, :, 2);
            blueChannel = img(:, :, 3);
            
            % Apply scaling
            redChannel = redChannel * redScale;
            greenChannel = greenChannel * greenScale;
            blueChannel = blueChannel * blueScale;
            
            % Maintain 0-1 rgb range
            redChannel = min(max(redChannel, 0), 1);
            greenChannel = min(max(greenChannel, 0), 1);
            blueChannel = min(max(blueChannel, 0), 1);
            
            % Combine channels
            editedImage = cat(3, redChannel, greenChannel, blueChannel);
        end
                
                    % Function to update the image
                    function updateImage(app, ax, orig, redSlider, greenSlider, blueSlider)
                        redScale = redSlider.Value;
                        greenScale = greenSlider.Value;
                        blueScale = blueSlider.Value;
                        editedImage = app.colorChange(orig, redScale, greenScale, blueScale);
                        imshow(editedImage, 'Parent', ax);
                        ax.UserData.EditedImage = editedImage;
                    end

                     % Back button function
                    function backButtonPushed(app, fig)
                        delete(fig);
                    end

                    % Save Changes button function
                function saveButtonPushed(app, ax, fig)
                    if isfield(ax.UserData, 'EditedImage')
                        editedImage = ax.UserData.EditedImage;
                        
                        % Get the original file name and path
                        [filepath, name, ext] = fileparts(app.SelectedImagePath);
                        
                        % Create new file name
                        newFileName = fullfile(filepath, [name '_color' ext]);
                        
                        % Ensure the new file name is unique
                        counter = 1;
                        while isfile(newFileName)
                            newFileName = fullfile(filepath, [name '_color_' num2str(counter) ext]);
                            counter = counter + 1;
                        end
                        
                        % Save the modified image
                        imwrite(editedImage, newFileName);
                        
                        % Update the main interface with the new image
                        app.setImagePath(newFileName);
                        
                        % Close the color adjustment figure
                        delete(fig);
                    else
                        uialert(fig, 'No edited image to save.', 'Save Error');
                    end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddimageButton
        function AddimageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp','Image Files(*.jpg;*.jpeg;*.png;*.bmp)'});
            if isequal(file,0)
                disp('User canceled selection');
            else
                app.SelectedImagePath = fullfile(path,file);
                app.Image.ImageSource = app.SelectedImagePath;
            end
        end

        % Button pushed function: PresetsButton
        function PresetsButtonPushed(app, event)
            if ~isempty(app.SelectedImagePath)
                presetsApp = presets;
                presetsApp.setImagePath(app.SelectedImagePath);
                presetsApp.UIFigure.Visible = 'on';
                delete(app);
            else
                uialert(app.UIFigure, 'No image selected.', 'Selection Error');
            end
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            % Check if an image has been selected
            if ~isempty(app.SelectedImagePath)
                cropApp = cropRotate;
                cropApp.setImagePath(app.SelectedImagePath);
                cropApp.UIFigure.Visible = 'on';
                delete(app);
            else
                uialert(app.UIFigure, 'No image selected.', 'Selection Error');
            end
        end

        % Button pushed function: BlurButton
        function BlurButtonPushed(app, event)
            if ~isempty(app.SelectedImagePath)
                % Load the original image
                orig = imread(app.SelectedImagePath);
                
                % Create a new figure for blur adjustment
                fig = uifigure('Name', 'Blur Adjustment');
                
                % Create a slider for blur scale
                blurSlider = uislider(fig, 'Position', [100 200 300 3], 'Limits', [0 10], 'Value', 5);
                
                % Create labels for the slider
                uilabel(fig, 'Position', [50 200 50 22], 'Text', 'Blur Scale');
                
                % Create an axes to display the image
                ax = uiaxes(fig, 'Position', [450 100 300 300]);
                
                % Create Back button
                backButton = uibutton(fig, 'push', 'Text', 'Back', ...
                    'Position', [100 50 100 22], ...
                    'ButtonPushedFcn', @(btn,event) app.backButtonPushed(fig));
                
                % Create Save Changes button
                saveButton = uibutton(fig, 'push', 'Text', 'Save Changes', ...
                    'Position', [650 50 100 22], ...
                    'ButtonPushedFcn', @(btn,event) app.saveButtonPushed(ax, fig));
                
                % Set up callbacks for the slider
                blurSlider.ValueChangedFcn = @(~,~) app.updateImageBlur(ax, orig, blurSlider);
                
                % Initial image display
                app.updateImageBlur(ax, orig, blurSlider);
            else
                uialert(app.UIFigure, 'No image selected', 'Selection Error');
            end
        end

        % Button pushed function: SaturationButton
        function SaturationButtonPushed(app, event)
            if ~isempty(app.SelectedImagePath)
                saturationApp = saturation;
                saturationApp.setImagePath(app.SelectedImagePath);
                saturationApp.UIFigure.Visible = 'on';
                delete(app);
            else
                uialert(app.UIFigure, 'No image selected.', 'Selection Error');
            end
        end

        % Button pushed function: BrightnessButton
        function BrightnessButtonPushed(app, event)
            if ~isempty(app.SelectedImagePath)
                brightnessApp = brightness;
                brightnessApp.setImagePath(app.SelectedImagePath);
                brightnessApp.UIFigure.Visible = 'on';
                delete(app);
            else
                uialert(app.UIFigure, 'No image selected.', 'Selection Error');
            end
        end

        % Button pushed function: ContrastButton
        function ContrastButtonPushed(app, event)
            if ~isempty(app.SelectedImagePath)
                contrastApp = contrast;
                contrastApp.setImagePath(app.SelectedImagePath);
                contrastApp.UIFigure.Visible = 'on';
                delete(app);
            else
                uialert(app.UIFigure, 'No image selected.', 'Selection Error');
            end
        end

        % Button pushed function: ColorButton
        function ColorButtonPushed(app, event)
            if ~isempty(app.SelectedImagePath)
                % Load the original image
                orig = imread(app.SelectedImagePath);
                
                % Create a new figure for color adjustment
                fig = uifigure('Name', 'Color Adjustment');
                
                % Create sliders for red, green, and blue scaling
                redSlider = uislider(fig, 'Position', [100 300 300 3], 'Limits', [0 5], 'Value', 1);
                greenSlider = uislider(fig, 'Position', [100 200 300 3], 'Limits', [0 5], 'Value', 1);
                blueSlider = uislider(fig, 'Position', [100 100 300 3], 'Limits', [0 5], 'Value', 1);
                
                % Create labels for sliders
                uilabel(fig, 'Position', [50 300 50 22], 'Text', 'Red');
                uilabel(fig, 'Position', [50 200 50 22], 'Text', 'Green');
                uilabel(fig, 'Position', [50 100 50 22], 'Text', 'Blue');
                
                % Create an axes to display the image
                ax = uiaxes(fig, 'Position', [450 100 300 300]);
                
                % Create Back button
                backButton = uibutton(fig, 'push', 'Text', 'Back', ...
                    'Position', [100 50 100 22], ...
                    'ButtonPushedFcn', @(btn,event) app.backButtonPushed(fig));
                
                % Create Save Changes button
                saveButton = uibutton(fig, 'push', 'Text', 'Save Changes', ...
                    'Position', [650 50 100 22], ...
                    'ButtonPushedFcn', @(btn,event) app.saveButtonPushed(ax, fig));
                
                % Set up callbacks for sliders
                redSlider.ValueChangedFcn = @(~,~) app.updateImage(ax, orig, redSlider, greenSlider, blueSlider);
                greenSlider.ValueChangedFcn = @(~,~) app.updateImage(ax, orig, redSlider, greenSlider, blueSlider);
                blueSlider.ValueChangedFcn = @(~,~) app.updateImage(ax, orig, redSlider, greenSlider, blueSlider);
                
                % Initial image display
                app.updateImage(ax, orig, redSlider, greenSlider, blueSlider);
            else
                uialert(app.UIFigure, 'No image selected', 'Selection Error');
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app.UIFigure);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if ~isempty(app.SelectedImagePath)
                % Read the currently displayed image
                editedImage = imread(app.Image.ImageSource);
                
                % Prompt user for file name and format
                [file, path, filterIndex] = uiputfile({'*.jpg;*.jpeg', 'JPEG Image'; '*.png', 'PNG Image'; '*.bmp', 'Bitmap Image'}, 'Save Image As');
                
                if isequal(file, 0)
                    disp('User canceled save operation');
                else
                    % Construct the full file path
                    [~, ~, ext] = fileparts(file);
                    
                    % If no extension is provided, default to .jpg
                    if isempty(ext)
                        switch filterIndex
                            case 1
                                ext = '.jpg';
                            case 2
                                ext = '.png';
                            case 3
                                ext = '.bmp';
                            otherwise
                                ext = '.jpg';
                        end
                        file = [file, ext];
                    end
                    
                    % Save the image
                    fullFileName = fullfile(path, file);
                    imwrite(editedImage, fullFileName);
                    disp(['Image saved as ', fullFileName]);
                end
            else
                uialert(app.UIFigure, 'No image to export.', 'Export Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CropButton = uibutton(app.UIFigure, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.Image = uiimage(app.UIFigure);
            app.ColorButton = uibutton(app.UIFigure, 'push');
            app.ColorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPushed, true);
            app.ColorButton.Text = 'Color';
            app.SaturationButton = uibutton(app.UIFigure, 'push');
            app.SaturationButton.ButtonPushedFcn = createCallbackFcn(app, @SaturationButtonPushed, true);
            app.SaturationButton.Text = 'Saturation';
            app.BrightnessButton = uibutton(app.UIFigure, 'push');
            app.BrightnessButton.ButtonPushedFcn = createCallbackFcn(app, @BrightnessButtonPushed, true);
            app.BrightnessButton.Text = 'Brightness';
            app.BlurButton = uibutton(app.UIFigure, 'push');
            app.BlurButton.ButtonPushedFcn = createCallbackFcn(app, @BlurButtonPushed, true);
            app.BlurButton.Text = 'Blur';
            app.ContrastButton = uibutton(app.UIFigure, 'push');
            app.ContrastButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastButtonPushed, true);
            app.ContrastButton.Text = 'Contrast';
            app.PresetsButton = uibutton(app.UIFigure, 'push');
            app.PresetsButton.ButtonPushedFcn = createCallbackFcn(app, @PresetsButtonPushed, true);
            app.PresetsButton.Text = 'Presets';
            app.AddimageButton = uibutton(app.UIFigure, 'push');
            app.AddimageButton.ButtonPushedFcn = createCallbackFcn(app, @AddimageButtonPushed, true);
            app.AddimageButton.Text = 'Add image';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = first_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end