classdef presets < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Image              matlab.ui.control.Image
        BackButton         matlab.ui.control.Button
        Y2KGameButton      matlab.ui.control.Button
        SadButton          matlab.ui.control.Button
        SaveChangesButton  matlab.ui.control.Button
        CinematicButton    matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImagePath
        SelectedImagePath
    end
    
    methods (Access = public)
        % Function to adjust and display the image
        function adjustAndDisplayImage(app)
            if ~isempty(app.ImagePath)
                img = imread(app.ImagePath);
                
                % Convert to double for calculations
                img = im2double(img);
                
                % Adjust contrast and decrease dark spots
                img = imadjust(img, [0.2 0.8], [0 1], 0.8);
                
                % Slightly increase blue
                img(:,:,3) = img(:,:,3) * 1.1;  % Increase blue by 10%
                
                % Ensure values are in valid range
                img = min(max(img, 0), 1);
                
                % Display the modified image in the UIAxes
                imshow(img, 'Parent', app.UIAxes);
                
                % Update axis labels and title
                title(app.UIAxes, 'Cinematic Effect');
                xlabel(app.UIAxes, '');
                ylabel(app.UIAxes, '');
                
                % Store the modified image in UserData
                app.UIAxes.UserData.ImageSource = img;
            end
        end

        function saddenImage(app)
            val = 2;
            sat = 0.25;
            if ~isempty(app.ImagePath)
                img = imread(app.ImagePath);
                
                img = im2double(img);
                % Turn to hsv
                hsvImage = rgb2hsv(img);
        
                v = hsvImage(:, :, 3);
        
                % Value Formula
                V = 0.5 + val * (v - 0.5);
                
                % Ensure Value does not exceed 1 or fall below 0
                V = min(max(V, 0), 1);
        
                % Apply new Value
                hsvImage(:,:,3) = V;
        
                % Adjust Saturation
                hsvImage(:,:,2) = hsvImage(:,:,2) * sat;
        
                % Convert HSV back to RGB
                sadImage = hsv2rgb(hsvImage);
        
                % Display sad image
                imshow(sadImage, 'Parent', app.UIAxes);
                app.UIAxes.UserData.ImageSource = sadImage;
            end
        end
        
        % Set the image path and display it
        function setImagePath(app, imagePath)
            app.ImagePath = imagePath;
            if ~isempty(app.ImagePath)
                app.Image.ImageSource = app.ImagePath;
                imageData = imread(app.ImagePath);
                imshow(imageData, 'Parent', app.UIAxes);
                app.UIAxes.UserData.ImageSource = imageData;
            end
        end

        function setSelectedImagePath(app, imagePath)
            app.SelectedImagePath = imagePath;
            if ~isempty(app.SelectedImagePath)
                app.Image.ImageSource = app.SelectedImagePath;
            end
        end

        % Functions for image processing
        function editedImage = initialY2KPreset(app, img)
            img = im2double(img);
            hsvimg = rgb2hsv(img);

            % Extract channels
            H = hsvimg(:,:,1);
            S = hsvimg(:,:,2);
            V = hsvimg(:,:,3);

            % Fixed changes in hsv
            S = min(S * 4.5, 1);
            V = min(V * 0.7, 1);

            filteredHSV = cat(3, H, S, V);
            editedImage1 = hsv2rgb(filteredHSV);

            % RGB version
            redChannel = editedImage1(:, :, 1);
            greenChannel = editedImage1(:, :, 2);
            blueChannel = editedImage1(:, :, 3);

            redChannel = redChannel * 2;
            greenChannel = greenChannel * 2;

            redChannel = min(max(redChannel, 0), 1);
            greenChannel = min(max(greenChannel, 0), 1);

            editedImage = cat(3, redChannel, greenChannel, blueChannel);
        end

        function finaleditedImage = flexibleCont(app, img, lowlim, highlim)
            img = im2double(img);
            limits = stretchlim(img, [lowlim highlim]);
            finaleditedImage = imadjust(img, limits, []);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CinematicButton
        function CinematicButtonPushed(app, event)
            app.adjustAndDisplayImage();
        end

        % Button pushed function: SaveChangesButton
        function SaveChangesButtonPushed(app, event)
            if isfield(app.UIAxes.UserData, 'ImageSource')
                if isfield(app.UIAxes.UserData, 'CroppedImage')
                    imageToSave = app.UIAxes.UserData.CroppedImage;
                else
                    imageToSave = app.UIAxes.UserData.ImageSource;
                end
                
                % Get the original file name and path
                [filepath, name, ext] = fileparts(app.ImagePath);
                
                % Create new file name
                newFileName = fullfile(filepath, [name '_preset' ext]);
                
                % Ensure the new file name is unique
                counter = 1;
                while isfile(newFileName)
                    newFileName = fullfile(filepath, [name '_preset_' num2str(counter) ext]);
                    counter = counter + 1;
                end
                
                % Save the modified image
                imwrite(imageToSave, newFileName);
                
                % Create a new instance of first_interface
                firstInterface = first_interface;
                
                % Set the image path in the new first_interface to the new file
                firstInterface.setImagePath(newFileName);
                
                % Make the new first_interface visible
                firstInterface.UIFigure.Visible = 'on';
                
                % Close the current cropRotate app
                delete(app);
            else
                uialert(app.UIFigure, 'No image to save.', 'Save Error');
            end
        end

        % Button pushed function: SadButton
        function SadButtonPushed(app, event)
            app.saddenImage();
        end

        % Button pushed function: Y2KGameButton
        function Y2KGameButtonPushed(app, event)
            if ~isempty(app.ImagePath)
                img = imread(app.ImagePath);
                
                % Apply initial Y2K preset
                editedImage = app.initialY2KPreset(img);
                
                % Apply flexible contrast adjustment
                finalImage = app.flexibleCont(editedImage, 0.05, 0.95);
                
                % Display the final edited image
                imshow(finalImage, 'Parent', app.UIAxes);
                app.UIAxes.UserData.ImageSource = finalImage;
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            firstInterface = first_interface;
            firstInterface.setImagePath(app.ImagePath);
            firstInterface.UIFigure.Visible = 'on';
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CinematicButton = uibutton(app.UIFigure, 'push');
            app.CinematicButton.ButtonPushedFcn = createCallbackFcn(app, @CinematicButtonPushed, true);
            app.CinematicButton.Text = 'Cinematic';
            app.SaveChangesButton = uibutton(app.UIFigure, 'push');
            app.SaveChangesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChangesButtonPushed, true);
            app.SaveChangesButton.Text = 'Save Changes';
            app.SadButton = uibutton(app.UIFigure, 'push');
            app.SadButton.ButtonPushedFcn = createCallbackFcn(app, @SadButtonPushed, true);
            app.SadButton.Text = 'Sad';
            app.Y2KGameButton = uibutton(app.UIFigure, 'push');
            app.Y2KGameButton.ButtonPushedFcn = createCallbackFcn(app, @Y2KGameButtonPushed, true);
            app.Y2KGameButton.Text = 'Y2K Game';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = presets

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end