classdef sad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        SadButton   matlab.ui.control.Button
        SaveButton  matlab.ui.control.Button
        Image       matlab.ui.control.Image
        UIAxes      matlab.ui.control.UIAxes
    end


    methods (Access = public)
        
        function sadImage = saddenImage(app)
            val = 1.9;
            sat = 0.50;
            if isempty(app.Image.ImageSource)
                app.Image.ImageSource = 'sample.jpg';
            end

            % Load the original image data
            imageData = imread(app.Image.ImageSource);
            
            % Turn to hsv
            hsvImage = rgb2hsv(imageData);

            v = hsvImage(:, :, 3);

            %Value Formula
            V = 0.5 + val * (v - 0.5);
            
            %Cannot Exceed 1, or below 0
            if V > 1
                V = 1;
            elseif V < 0
                V = 0;
            end

            %Apply new Value
            hsvImage(:,:,3) = V;

            hsvImage(:,:,2) = hsvImage(:,:,2) * sat;
             

            % Convert HSV back to RGB
            sadImage = hsv2rgb(hsvImage);

            % Display rotated image
            imshow(sadImage, 'Parent', app.UIAxes);
            app.UIAxes.UserData.ImageSource = sadImage;
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SadButton
        function SadButtonPushed(app, event)
            
            saddenImage(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sad')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Image = uiimage(app.UIFigure);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.SadButton = uibutton(app.UIFigure, 'push');
            app.SadButton.ButtonPushedFcn = createCallbackFcn(app, @SadButtonPushed, true);
            app.SadButton.Text = 'Sad';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sad

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end