classdef saturation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        BackButton             matlab.ui.control.Button
        SaveChangesButton      matlab.ui.control.Button
        SaturationSlider       matlab.ui.control.Slider
        SaturationSliderLabel  matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        UIAxes                 matlab.ui.control.UIAxes
    end

    properties (Access = private)
        ImagePath;
    end
    
    methods (Access = public)
        function setImagePath(app, imagePath)
            app.ImagePath = imagePath;
            if ~isempty(app.ImagePath)
                app.Image.ImageSource = app.ImagePath;
                imageData = imread(app.ImagePath);
                imshow(imageData, 'Parent', app.UIAxes);
                app.UIAxes.UserData.ImageSource = imageData;
                title(app.UIAxes, 'Original Image');
                
                % Initialize with default saturation
                app.saturateImage(app.SaturationSlider.Value);
            end
        end
    end

    methods (Access = private)
        function saturateImage(app, saturationMultiplier)
            if ~isempty(app.ImagePath)
                imageData = app.UIAxes.UserData.ImageSource;
                hsvImage = rgb2hsv(imageData);
                
                hsvImage(:,:,2) = hsvImage(:,:,2) * saturationMultiplier;
                hsvImage(hsvImage > 1) = 1; 
    
                saturatedImage = hsv2rgb(hsvImage);
                
                imshow(saturatedImage, 'Parent', app.UIAxes);
                app.UIAxes.UserData.SaturatedImage = saturatedImage;
                title(app.UIAxes, 'Saturated Image');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SaturationSlider
        function SaturationSliderValueChanged(app, event)
            saturationMultiplier = event.Value;
            app.saturateImage(saturationMultiplier);
        end

        % Button pushed function: SaveChangesButton
        function SaveChangesButtonPushed(app, event)
            if isfield(app.UIAxes.UserData, 'ImageSource')
                if isfield(app.UIAxes.UserData, 'SaturatedImage')
                    imageToSave = app.UIAxes.UserData.SaturatedImage;
                else
                    imageToSave = app.UIAxes.UserData.ImageSource;
                end
                
                % Get the original file name and path
                [filepath, name, ext] = fileparts(app.ImagePath);
                
                % Create new file name
                newFileName = fullfile(filepath, [name '_saturated' ext]);
                
                % Ensure the new file name is unique
                counter = 1;
                while isfile(newFileName)
                    newFileName = fullfile(filepath, [name '_saturated_' num2str(counter) ext]);
                    counter = counter + 1;
                end
                
                % Save the modified image
                imwrite(imageToSave, newFileName);
                
                % Create a new instance of first_interface
                firstInterface = first_interface;
                
                % Set the image path in the new first_interface to the new file
                firstInterface.setImagePath(newFileName);
                
                % Make the new first_interface visible
                firstInterface.UIFigure.Visible = 'on';
                
                % Close the current saturation app
                delete(app);
            else
                uialert(app.UIFigure, 'No image to save.', 'Save Error');
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            firstInterface = first_interface;
            firstInterface.setImagePath(app.ImagePath);
            firstInterface.UIFigure.Visible = 'on';
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Saturation')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Image = uiimage(app.UIFigure);
            app.SaturationSliderLabel = uilabel(app.UIFigure);
            app.SaturationSliderLabel.Text = 'Saturation';
            app.SaturationSlider = uislider(app.UIFigure);
            app.SaturationSlider.Limits = [1 2];
            app.SaturationSlider.ValueChangedFcn = createCallbackFcn(app, @SaturationSliderValueChanged, true);
            app.SaturationSlider.Value = 1;
            app.SaveChangesButton = uibutton(app.UIFigure, 'push');
            app.SaveChangesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChangesButtonPushed, true);
            app.SaveChangesButton.Text = 'Save Changes';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = saturation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end